classdef ppt_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NumPPTGUIFigure               matlab.ui.Figure
        current_plot                  matlab.ui.control.UIAxes
        LcEditFieldLabel              matlab.ui.control.Label
        Lc                            matlab.ui.control.NumericEditField
        LpeEditFieldLabel             matlab.ui.control.Label
        Lpe                           matlab.ui.control.NumericEditField
        LeEditFieldLabel              matlab.ui.control.Label
        Le                            matlab.ui.control.NumericEditField
        RcEditFieldLabel              matlab.ui.control.Label
        Rc                            matlab.ui.control.NumericEditField
        ReEditFieldLabel              matlab.ui.control.Label
        Re                            matlab.ui.control.NumericEditField
        RpeEditFieldLabel             matlab.ui.control.Label
        Rpe                           matlab.ui.control.NumericEditField
        RpEditFieldLabel              matlab.ui.control.Label
        Rp                            matlab.ui.control.NumericEditField
        CEditFieldLabel               matlab.ui.control.Label
        C                             matlab.ui.control.NumericEditField
        SolveButton                   matlab.ui.control.StateButton
        V0EditFieldLabel              matlab.ui.control.Label
        V0                            matlab.ui.control.NumericEditField
        hEditFieldLabel               matlab.ui.control.Label
        h                             matlab.ui.control.NumericEditField
        wEditFieldLabel               matlab.ui.control.Label
        w                             matlab.ui.control.NumericEditField
        lEditFieldLabel               matlab.ui.control.Label
        l                             matlab.ui.control.NumericEditField
        TeEditFieldLabel              matlab.ui.control.Label
        Te                            matlab.ui.control.NumericEditField
        n_eEditFieldLabel             matlab.ui.control.Label
        n_e                           matlab.ui.control.NumericEditField
        t_pulseEditFieldLabel         matlab.ui.control.Label
        t_pulse                       matlab.ui.control.NumericEditField
        deltaEditFieldLabel           matlab.ui.control.Label
        delta                         matlab.ui.control.NumericEditField
        alphaEditFieldLabel           matlab.ui.control.Label
        alpha                         matlab.ui.control.NumericEditField
        m_bitEditFieldLabel           matlab.ui.control.Label
        m_bit                         matlab.ui.control.NumericEditField
        muEditFieldLabel              matlab.ui.control.Label
        mu                            matlab.ui.control.NumericEditField
        MaxStepEditFieldLabel         matlab.ui.control.Label
        MaxStep                       matlab.ui.control.NumericEditField
        t_finalEditFieldLabel         matlab.ui.control.Label
        t_final                       matlab.ui.control.NumericEditField
        energy_plot                   matlab.ui.control.UIAxes
        ThrusterGeometry              matlab.ui.container.ButtonGroup
        ParallelPlateButton           matlab.ui.control.RadioButton
        CoaxialButton                 matlab.ui.control.RadioButton
        I_bitEditFieldLabel           matlab.ui.control.Label
        I_bit                         matlab.ui.control.NumericEditField
        AbsTolEditFieldLabel          matlab.ui.control.Label
        AbsTol                        matlab.ui.control.NumericEditField
        RelTolEditFieldLabel          matlab.ui.control.Label
        RelTol                        matlab.ui.control.NumericEditField
        NumericalMethodDropDownLabel  matlab.ui.control.Label
        NumericalMethod               matlab.ui.control.DropDown
        r_inLabel                     matlab.ui.control.Label
        r_in                          matlab.ui.control.NumericEditField
        r_outLabel                    matlab.ui.control.Label
        r_out                         matlab.ui.control.NumericEditField
        gLabel                        matlab.ui.control.Label
        g                             matlab.ui.control.NumericEditField
        v_eLabel                      matlab.ui.control.Label
        exhaust_v                     matlab.ui.control.NumericEditField
        IspLabel                      matlab.ui.control.Label
        Isp                           matlab.ui.control.NumericEditField
        vel_pos                       matlab.ui.control.UIAxes
        ClearButton                   matlab.ui.control.Button
        StatusEditFieldLabel          matlab.ui.control.Label
        Status                        matlab.ui.control.EditField
        DynamicalModel                matlab.ui.container.ButtonGroup
        SlugButton                    matlab.ui.control.RadioButton
        ModSlugButton                 matlab.ui.control.RadioButton
        n_tLabel                      matlab.ui.control.Label
        n_t                           matlab.ui.control.NumericEditField
        gammaEditFieldLabel           matlab.ui.control.Label
        gamma                         matlab.ui.control.NumericEditField
        REditFieldLabel               matlab.ui.control.Label
        R                             matlab.ui.control.NumericEditField
        V_criticEditFieldLabel        matlab.ui.control.Label
        V_critic                      matlab.ui.control.NumericEditField
        PPTExampleDropDownLabel       matlab.ui.control.Label
        PPTExample                    matlab.ui.control.DropDown
        ElectricParameterLabel        matlab.ui.control.Label
        ThrusterGeometryLabel         matlab.ui.control.Label
        PlasmaPropertiesLabel         matlab.ui.control.Label
        ConstantValuesLabel           matlab.ui.control.Label
        NumericalOptionsLabel         matlab.ui.control.Label
        OthersLabel                   matlab.ui.control.Label
        ResultsLabel                  matlab.ui.control.Label
        PreDefinedConfigLabel         matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.NumPPTGUIFigure.WindowState = 'maximized';            
        end

        % Value changed function: SolveButton
        function SolveButtonValueChanged(app, event)
            %limpa os resultados
%             app.ClearButtonPushed(@ClearButtonPushed);
%             ClearButtonPushed(app, matlab.ui.eventdata.ClearButtonPushed)
            run_sucess = 1;
            tspan = [0 app.t_final.Value];
            app.Status.Value = "Iniciando";
%             y0 = [0,0,0,0,app.m_bit.Value];
%             y0 = [0,0,0,0];
            opts = odeset('RelTol',app.RelTol.Value,'AbsTol',app.AbsTol.Value,'MaxStep', app.MaxStep.Value);
%             app.Rp.Value = 8.08*app.h.Value*sqrt(app.mu.Value*log(1.24e7*(app.Te.Value^3/app.n_e.Value)^0.5)/app.t_pulse.Value)/(app.w.Value*app.Te.Value^(0.75));
%             Rplasma = 8.08*app.h.Value*sqrt(app.mu.Value*log(1.24e7*((app.Te.Value*11604.5250061)^3/app.n_e.Value)^0.5)/app.t_pulse.Value)/(app.w.Value*(app.Te.Value*11604.5250061)^(0.75))
%             Req = app.Rc.Value + app.Re.Value + app.Rpe.Value + Rplasma;
            
            
            if app.ParallelPlateButton.Value
                if app.SlugButton.Value
                    y0 = [0,0,0,0];
                    switch app.NumericalMethod.Value
                        case 'ode23'
                            [t,y] = ode23(@(t,y) ppt_parallel(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value), tspan, y0, opts);
                        case 'ode45'
                            [t,y] = ode45(@(t,y) ppt_parallel(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value), tspan, y0, opts);
                        case 'ode113'
                            [t,y] = ode113(@(t,y) ppt_parallel(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value), tspan, y0, opts);
                    end
                    Ekinetic = app.m_bit.Value*y(:,3).*y(:,3)/2;
                    Eb = (0.5)*(app.Lc.Value+app.Le.Value + app.mu.Value*app.h.Value.*y(:,1)/app.w.Value).*y(:,4).^2;
                    I_bit = zeros(length(t),1);
                    for i = 2:length(t)
                        int = trapz(t(1:i),app.mu.Value*app.h.Value.*y(1:i,4).*y(1:i,4)./(2.*app.w.Value));
                        I_bit(i) = int;
                    end
                else
                    y0 = [0,0,0,0,app.m_bit.Value];
                    switch app.NumericalMethod.Value
                        case 'ode23'
                            [t,y] = ode23(@(t,y) ppt_parallel_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                        case 'ode45'
                            [t,y] = ode45(@(t,y) ppt_parallel_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                        case 'ode113'
                            [t,y] = ode113(@(t,y) ppt_parallel_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                    end
                    Ekinetic = y(:,5).*y(:,3).*y(:,3)/2;
%                     plot(t, [0;diff(y(:,5))])
                    plot(t,y(:,5))
                    ylabel('Mass flow rate')
                    m_t = [0; diff(y(:,5))];
                    
                    C_m = app.gamma.Value*(2/(app.gamma.Value+1))^(app.gamma.Value+1/(2*(app.gamma.Value-1)));
                    C_f = app.gamma.Value*(2/(app.gamma.Value+1))^(app.gamma.Value/(app.gamma.Value-1)) + (1+(app.gamma.Value-1)/2)^(-app.gamma.Value/(app.gamma.Value-1));
                
                    Eb = (0.5)*(app.Lc.Value+app.Le.Value + app.mu.Value*app.h.Value.*y(:,1)/app.w.Value).*y(:,4).^2;
                    I_bit = zeros(length(t),1);
                    for i = 2:length(t)
                        int = trapz(t(1:i),app.mu.Value*app.h.Value.*y(1:i,4).*y(1:i,4)./(2.*app.w.Value) + m_t(1:i).*sqrt(app.gamma.Value.*app.R.Value.*app.Te.Value).*(C_f/C_m));
                        I_bit(i) = int;
                    end
                end
                
            else
                if app.SlugButton.Value
                    y0 = [0,0,0,0];
                    switch app.NumericalMethod.Value
                        case 'ode23'
                            [t,y] = ode23(@(t,y) ppt_coaxial(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value), tspan, y0, opts);
                        case 'ode45'
                            [t,y] = ode45(@(t,y) ppt_coaxial(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value), tspan, y0, opts);
                        case 'ode113'
                            [t,y] = ode113(@(t,y) ppt_coaxial(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value), tspan, y0, opts);
                    end
                    Ekinetic = app.m_bit.Value*y(:,3).*y(:,3)/2;
                    
                else
                    y0 = [0,0,0,0,app.m_bit.Value];
                    switch app.NumericalMethod.Value
                        case 'ode23'
                            [t,y] = ode23(@(t,y) ppt_coaxial_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                        case 'ode45'
                            [t,y] = ode45(@(t,y) ppt_coaxial_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                        case 'ode113'
                            [t,y] = ode113(@(t,y) ppt_coaxial_modslug(t,y,app.V0.Value,app.C.Value,app.Rc.Value,app.Re.Value,app.Rpe.Value,app.mu.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value,app.Te.Value,app.n_e.Value,app.t_pulse.Value,app.r_out.Value,app.r_in.Value,app.gamma.Value,app.R.Value,app.V_critic.Value), tspan, y0, opts);
                    end
                    Ekinetic = y(:,5).*y(:,3).*y(:,3)/2;
                    plot(t, [0;diff(y(:,5))])
                    ylabel('Mass flow rate')
                    
                end
                Eb = (0.5)*(app.Lc.Value+app.Le.Value+app.mu.Value*log(app.r_out.Value/app.r_in.Value)*y0(1)/(4*pi)).*y(:,4).^2;
                I_bit = zeros(length(t),1);
                for i = 2:length(t)
                    int = trapz(t(1:i),app.mu.Value.*log(app.r_out.Value./app.r_in.Value).*y(1:i,4).*y(1:i,4)./(4.*app.m_bit.Value*pi));
                    I_bit(i) = int;
                end
            end
            
            I = y(:,4);
            V = app.V0.Value - y(:,2)/app.C.Value;
            Ec = app.C.Value*V.^2/2;
            Eohm = zeros(length(t),1);
            
            
            for i = 2:length(t)
                int = trapz(t(1:i),Req*y(1:i,4).*y(1:i,4));
                Eohm(i) = int;
            end
            
            
            %plot 1
            yyaxis(app.current_plot,'left')
            plot(app.current_plot,t,V)
            yyaxis(app.current_plot,'right')
            plot(app.current_plot,t,I)
            ylabel(app.current_plot,'Current (A)')
            xlim(app.current_plot,[0,app.t_final.Value])
            
            %plot 2
            plot(app.energy_plot,t,Eohm) 
            hold(app.energy_plot,'on')
            plot(app.energy_plot,t,Ec)
            plot(app.energy_plot,t,Ekinetic)
            plot(app.energy_plot,t,Eb)
            plot(app.energy_plot,t,Eohm+Ec+Ekinetic+Eb)
            legend(app.energy_plot,'Ohmic','Capacitor','Kinetic','Indutance Field','Total')
            xlim(app.energy_plot,[0,app.t_final.Value])
            hold(app.energy_plot,'off')
            
            %exhaust velocity, Isp
%             I_bit = zeros(length(t),1);
%             for i = 2:length(t)
%                 int = trapz(t(1:i),app.mu.Value*app.h.Value.*y(1:i,4).*y(1:i,4)./(2.*app.w.Value));
%                 I_bit(i) = int;
%             end
             try
                index = find(abs(y(:,1)-app.l.Value) < 0.001,1)
                t_prime = t(index)
                app.exhaust_v.Value = y(index,3);
                app.Isp.Value = app.exhaust_v.Value/app.g.Value;
                app.I_bit.Value = I_bit(end);

                
             catch
                run_sucess = 0;
                disp('Teste');
                app.Status.Value = "Tempo insuficiente";
                app.t_final.Value = 1.10*app.t_final.Value;
                SolveButtonValueChanged(app,[]);
                yyaxis(app.vel_pos, 'right')
                plot(app.vel_pos,t,y(:,1))
%               hold(app.vel_pos, 'on')
                ylabel(app.vel_pos, 'Position (m)')
                yyaxis(app.vel_pos, 'left')
                plot(app.vel_pos,t,y(:,3))
                xlim(app.vel_pos,[0,app.t_final.Value])
             end
            
            %thurst efficiency considering slug model
            app.n_t.Value = 100*app.m_bit.Value.*app.exhaust_v.Value.*app.exhaust_v.Value./(2*Ec(1));
            %plot 3
            yyaxis(app.vel_pos, 'right')
            plot(app.vel_pos,t,y(:,1))
%             hold(app.vel_pos, 'on')
            ylabel(app.vel_pos, 'Position (m)')
            yyaxis(app.vel_pos, 'left')
            plot(app.vel_pos,t,y(:,3))
            xlim(app.vel_pos,[0,app.t_final.Value])
%             hold(app.vel_pos, 'off')
            if run_sucess
                 app.Status.Value = "Success";
                 voltage = V;
                 time = t;
                 i_bit = app.I_bit.Value;
                 isp = app.Isp.Value;
                 v_e = app.exhaust_v.Value;
                 n_t = app.n_t.Value;
                 x_s = y(:,1);
                 xdot_s = y(:,3);
                 if app.ParallelPlateButton.Value
                    if app.SlugButton.Value
                        if isfile('ppt_parallel_slug.mat')
                            save('ppt_parallel_slug1.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        else
                            save('ppt_parallel_slug.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        end
                    else
                        if isfile('ppt_parallel_modslug.mat')
                            save('ppt_parallel_modslug1.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        else
                            save('ppt_parallel_modslug.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        end
                    end
                 else
                     if app.SlugButton.Value
                        if isfile('ppt_coax_slug.mat')
                            save('ppt_coax_slug1.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        else
                            save('ppt_coax_slug.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        end
                    else
                        if isfile('ppt_coax_modslug.mat')
                            save('ppt_coax_modslug1.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        else
                            save('ppt_coax_modslug.mat','voltage','time','i_bit','isp','v_e','n_t','x_s','xdot_s', 'Ec','Eohm','Ekinetic','Eb');
                        end
                    end
                 end
            end
            
            function y = ppt_parallel(t,y0,V0,C,Rc,Re,Rpe,mu,h,w,m_bit,Lc,Le,delta,Te,n_e,t_pulse)
                Lpe = mu*h*y0(1)/w +mu*delta*h/(2*w);
                L = Lpe + Lc + Le;
                Rplasma = 8.08*h*sqrt(mu*log(1.24e7*((Te*11604.5250061)^3/n_e)^0.5)/t_pulse)/(w*(Te*11604.5250061)^(0.75));
                Req = Rc + Re + Rpe + Rplasma;
    
                y = zeros(4,1);
                y(1) = y0(3);
                y(2) = y0(4);
                y(3) = mu*h*y0(4)*y0(4)/(2*m_bit*w);
                y(4) = (-y0(2)/C - mu*h*y0(3)*y0(4)/w - Req*y0(4) + V0)/(L);

            end
        
            function y = ppt_parallel_modslug(t,y0,V0,C,Rc,Re,Rpe,mu,h,w,m_bit,Lc,Le,delta,Te,n_e,t_pulse,gamma,R,V_critic)
                Lpe = mu*h*y0(1)/w +mu*delta*h/(2*w);
                L = Lpe + Lc + Le;
                Rplasma = 8.08*h*sqrt(mu*log(1.24e7*((Te*11604.5250061)^3/n_e)^0.5)/t_pulse)/(w*(Te*11604.5250061)^(0.75));
                Req = Rc + Re + Rpe + Rplasma;
                
                m_dot = y0(4)*y0(4)*mu*h/(4.404*w*V_critic);
                C_m = gamma*(2/(gamma+1))^((gamma+1)/(2*(gamma-1)));
                C_f = gamma*(2/(gamma+1))^(gamma/(gamma-1)) + (1+(gamma-1)/2)^(-gamma/(gamma-1));
                
                y = zeros(5,1);
                y(1) = y0(3);
                y(2) = y0(4);
                y(3) = ((mu*h*y0(4)*y0(4)/(2*w) - m_dot*y0(1) + m_dot*sqrt(gamma*R*Te)*(C_f/C_m)))/y0(5);
                y(4) = (-y0(2)/C - mu*h*y0(3)*y0(4)/w - Req*y0(4) + V0)/(L);
                y(5) = m_dot;

            end
        
            function y = ppt_coaxial(t,y0,V0,C,Rc,Re,Rpe,mu,m_bit,Lc,Le,delta,Te,n_e,t_pulse,r_out,r_in)
                Lpe = mu*log(r_out/r_in)*y0(1)/(4*pi) + mu*delta*log(r_out/r_in)/(4*pi);
                L = Lpe + Lc + Le;
                
                Rplasma = 2.57*(r_out-r_in)*sqrt(mu*log(1.24e7*((Te*11604.5250061)^3/n_e)^0.5)/t_pulse)/((r_out+r_in)*(Te*11604.5250061)^(0.75));
                Req = Rc + Re + Rpe + Rplasma;
                
                y = zeros(4,1);
                y(1) = y0(3);
                y(2) = y0(4);
                y(3) = mu*log(r_out/r_in)*y0(4)*y0(4)/(4*m_bit*pi);
                y(4) = (-y0(2)/C - mu*log(r_out/r_in)*y0(3)*y0(4)/(4*pi) - Req*y0(4) + V0)/(L);

            end
        
            function y = ppt_coaxial_modslug(t,y0,V0,C,Rc,Re,Rpe,mu,m_bit,Lc,Le,delta,Te,n_e,t_pulse,r_out,r_in,gamma, R,V_critic)
                Lpe = mu*log(r_out/r_in)*y0(1)/(4*pi) + mu*delta*log(r_out/r_in)/(4*pi);
                L = Lpe + Lc + Le;
                
                Rplasma = 2.57*(r_out-r_in)*sqrt(mu*log(1.24e7*((Te*11604.5250061)^3/n_e)^0.5)/t_pulse)/((r_out+r_in)*(Te*11604.5250061)^(0.75));
                Req = Rc + Re + Rpe + Rplasma;
                
                m_dot = y0(4)*y0(4)*mu*log(r_out/r_in)/(4.404*V_critic);
                C_m = gamma*(2/(gamma+1))^(gamma+1/(2*(gamma-1)));
                C_f = gamma*(2/(gamma+1))^(gamma/(gamma-1)) + (1+(gamma-1)/2)^(-gamma/(gamma-1));
                
                y = zeros(5,1);
                y(1) = y0(3);
                y(2) = y0(4);
                y(3) = (mu*log(r_out/r_in)*y0(4)*y0(4) - m_dot*y0(1) + m_dot*sqrt(gamma*R*Te)*(C_f/C_m))/(4*y0(5)*pi);
                y(4) = (-y0(2)/C - mu*log(r_out/r_in)*y0(3)*y0(4)/(4*pi) - Req*y0(4) + V0)/(L);
                y(5) = m_dot;
            end
        end

        % Selection changed function: ThrusterGeometry
        function ThrusterGeometrySelectionChanged(app, event)
            selectedButton = app.ThrusterGeometry.SelectedObject;
            
            if app.CoaxialButton.Value
                app.r_in.Editable = 'on';
                app.r_out.Editable = 'on';
                app.h.Editable = 'off';
                app.w.Editable = 'off';
            else
                app.r_in.Editable = 'off';
                app.r_out.Editable = 'off';
                app.h.Editable = 'on';
                app.w.Editable = 'on';
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.I_bit.Value = 0;
            app.exhaust_v.Value = 0;
            app.Isp.Value = 0;
            app.Status.Value = " ";
            app.n_t.Value = 0;
        end

        % Value changed function: PPTExample
        function PPTExampleValueChanged(app, event)
            value = app.PPTExample.Value;
            switch value
                case "LES 6"
                    app.Lc.Value = 0;
                    app.Lpe.Value = 0;
                    app.Le.Value = 3.4e-8;
                    app.Rc.Value = 0.03;
                    app.Re.Value = 0;
                    app.Rpe.Value = 0;
                    app.Rp.Value = 0.074;
                    app.C.Value = 2e-6;
                    app.V0.Value = 1360;
                    app.h.Value = 0.03;
                    app.w.Value = 0.01;
                    app.l.Value = 0.006;
                    app.Te.Value = 1.5;
                    app.n_e.Value = 1e21;
                    app.t_pulse.Value = 4e-7;
                    app.delta.Value = 0;
                    app.alpha.Value = 1;
                    app.m_bit.Value = 1e-8;
                    app.mu.Value = 1.257e-6;
                    app.MaxStep.Value = 1e-8;
                    app.AbsTol.Value = 1e-6;
                    app.RelTol.Value = 0.0001;
                    app.t_final.Value = 2.5e-6;
                    app.g.Value = 9.81;
                    %app.r_in.Value = 0.025;
                    %app.r_out.Value = 0.03;
                    app.gamma.Value = 1;
                    app.R.Value = 8.314;
                    app.V_critic.Value = 13300;
                case "LES 8/9"
                    app.Lc.Value = 0;
                    app.Lpe.Value = 0;
                    app.Le.Value = 3.5e-8;
                    app.Rc.Value = 0.03;
                    app.Re.Value = 0;
                    app.Rpe.Value = 0;
                    app.Rp.Value = 0.074;
                    app.C.Value = 17e-6;
                    app.V0.Value = 1538;
                    app.h.Value = 0.0254;
                    app.w.Value = 0.0254;
                    app.l.Value = 0.0254;
                    app.Te.Value = 5;
                    app.n_e.Value = 1e21;
                    app.t_pulse.Value = 10e-7;
                    app.delta.Value = 0;
                    app.alpha.Value = 1;
                    app.m_bit.Value = 2.85e-8;
                    app.mu.Value = 1.257e-6;
                    app.MaxStep.Value = 1e-8;
                    app.AbsTol.Value = 1e-6;
                    app.RelTol.Value = 0.0001;
                    app.t_final.Value = 2.5e-6;
                    app.g.Value = 9.81;
                    %app.r_in.Value = 0.025;
                    %app.r_out.Value = 0.03;
                    app.gamma.Value = 1;
                    app.R.Value = 8.314;
                    app.V_critic.Value = 13300;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NumPPTGUIFigure = uifigure;
            app.NumPPTGUIFigure.Name = 'NumPPTG';
            app.current_plot = uiaxes(app.NumPPTGUIFigure);
            title(app.current_plot, '')
            xlabel(app.current_plot, 'Time')
            ylabel(app.current_plot, 'Voltage (V)')
            app.LcEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.LcEditFieldLabel.Text = 'Lc';
            app.Lc = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.LpeEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.LpeEditFieldLabel.Text = 'Lpe';
            app.Lpe = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.LeEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.LeEditFieldLabel.Text = 'Le';
            app.Le = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.Le.Value = 3.4e-08;
            app.RcEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.RcEditFieldLabel.Text = 'Rc';
            app.Rc = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.Rc.Value = 0.03;
            app.ReEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.ReEditFieldLabel.Text = 'Re';
            app.Re = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.RpeEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.RpeEditFieldLabel.Text = 'Rpe';
            app.Rpe = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.RpEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.RpEditFieldLabel.Text = 'Rp';
            app.Rp = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.Rp.Value = 0.074;
            app.CEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.C = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.C.Value = 2e-06;
            app.SolveButton = uibutton(app.NumPPTGUIFigure, 'state');
            app.SolveButton.ValueChangedFcn = createCallbackFcn(app, @SolveButtonValueChanged, true);
            app.SolveButton.Text = 'Solve';
            app.V0EditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.V0EditFieldLabel.Text = 'V0';
            app.V0 = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.V0.Value = 1360;
            app.hEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.hEditFieldLabel.Text = 'h';
            app.h = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.h.Value = 0.03;
            app.wEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.wEditFieldLabel.Text = 'w';
            app.w = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.w.Value = 0.01;
            app.lEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.lEditFieldLabel.Text = 'l';
            app.l = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.l.Value = 0.006;
            app.TeEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.TeEditFieldLabel.Text = 'Te';
            app.Te = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.Te.Value = 1.5;
            app.n_eEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.n_eEditFieldLabel.Text = 'n_e';
            app.n_e = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.n_e.Value = 1e+21;
            app.t_pulseEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.t_pulseEditFieldLabel.Text = 't_pulse';
            app.t_pulse = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.t_pulse.Value = 4e-07;
            app.deltaEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.deltaEditFieldLabel.Text = 'delta';
            app.delta = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alpha = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.alpha.Value = 1;
            app.m_bitEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.m_bitEditFieldLabel.Text = 'm_bit';
            app.m_bit = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.m_bit.Value = 1e-08;
            app.muEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.muEditFieldLabel.Text = 'mu';
            app.mu = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.mu.Value = 1.2566e-06;
            app.MaxStepEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.MaxStepEditFieldLabel.Text = 'MaxStep';
            app.MaxStep = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.MaxStep.Value = 1e-08;
            app.t_finalEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.t_finalEditFieldLabel.Text = 't_final';
            app.t_final = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.t_final.Value = 2.5e-06;
            app.energy_plot = uiaxes(app.NumPPTGUIFigure);
            title(app.energy_plot, '')
            xlabel(app.energy_plot, 'Time')
            ylabel(app.energy_plot, 'Energy (J)')
            app.ThrusterGeometry = uibuttongroup(app.NumPPTGUIFigure);
            app.ThrusterGeometry.SelectionChangedFcn = createCallbackFcn(app, @ThrusterGeometrySelectionChanged, true);
            app.ThrusterGeometry.Title = 'Thruster Geometry';
            app.ParallelPlateButton = uiradiobutton(app.ThrusterGeometry);
            app.ParallelPlateButton.Text = 'Parallel Plate';
            app.ParallelPlateButton.Value = true;
            app.CoaxialButton = uiradiobutton(app.ThrusterGeometry);
            app.CoaxialButton.Text = 'Coaxial';
            app.I_bitEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.I_bitEditFieldLabel.Text = 'I_bit';
            app.I_bit = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.I_bit.ValueDisplayFormat = '%.6f';
            app.AbsTolEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.AbsTolEditFieldLabel.Text = 'AbsTol';
            app.AbsTol = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.AbsTol.Value = 1e-06;
            app.RelTolEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.RelTolEditFieldLabel.Text = 'RelTol';
            app.RelTol = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.RelTol.Value = 0.0001;
            app.NumericalMethodDropDownLabel = uilabel(app.NumPPTGUIFigure);
            app.NumericalMethodDropDownLabel.Text = 'Numerical Method';
            app.NumericalMethod = uidropdown(app.NumPPTGUIFigure);
            app.NumericalMethod.Items = {'ode23', 'ode45', 'ode113'};
            app.NumericalMethod.Value = 'ode45';
            app.r_inLabel = uilabel(app.NumPPTGUIFigure);
            app.r_inLabel.Text = 'r_in';
            app.r_in = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.r_in.Editable = 'off';
            app.r_in.Value = 0.025;
            app.r_outLabel = uilabel(app.NumPPTGUIFigure);
            app.r_outLabel.Text = 'r_out';
            app.r_out = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.r_out.Editable = 'off';
            app.r_out.Value = 0.03;
            app.gLabel = uilabel(app.NumPPTGUIFigure);
            app.gLabel.Text = 'g';
            app.g = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.g.Value = 9.81;
            app.v_eLabel = uilabel(app.NumPPTGUIFigure);
            app.v_eLabel.Text = 'v_e';
            app.exhaust_v = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.exhaust_v.ValueDisplayFormat = '%.5f';
            app.IspLabel = uilabel(app.NumPPTGUIFigure);
            app.IspLabel.Text = 'Isp';
            app.Isp = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.Isp.ValueDisplayFormat = '%.5f';
            app.vel_pos = uiaxes(app.NumPPTGUIFigure);
            title(app.vel_pos, '')
            xlabel(app.vel_pos, 'Time')
            ylabel(app.vel_pos, {'Velocity (m/s)'; ''})
            app.ClearButton = uibutton(app.NumPPTGUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.StatusEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.Status = uieditfield(app.NumPPTGUIFigure, 'text');
            app.DynamicalModel = uibuttongroup(app.NumPPTGUIFigure);
            app.DynamicalModel.Title = 'Dynamical Model';
            app.SlugButton = uiradiobutton(app.DynamicalModel);
            app.SlugButton.Text = 'Slug';
            app.SlugButton.Value = true;
            app.ModSlugButton = uiradiobutton(app.DynamicalModel);
            app.ModSlugButton.Text = 'Mod. Slug';
            app.n_tLabel = uilabel(app.NumPPTGUIFigure);
            app.n_tLabel.Text = {'n_t'; ''};
            app.n_t = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.n_t.ValueDisplayFormat = '%.5f';
            app.gammaEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gamma = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.gamma.Value = 1;
            app.REditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.REditFieldLabel.Text = 'R';
            app.R = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.R.Value = 8.314;
            app.V_criticEditFieldLabel = uilabel(app.NumPPTGUIFigure);
            app.V_criticEditFieldLabel.Text = 'V_critic';
            app.V_critic = uieditfield(app.NumPPTGUIFigure, 'numeric');
            app.V_critic.Value = 13300;
            app.PPTExampleDropDownLabel = uilabel(app.NumPPTGUIFigure);
            app.PPTExampleDropDownLabel.Text = 'PPT Example';
            app.PPTExample = uidropdown(app.NumPPTGUIFigure);
            app.PPTExample.Items = {'LES 6', 'LES 8/9', 'Option 3', 'Option 4'};
            app.PPTExample.ValueChangedFcn = createCallbackFcn(app, @PPTExampleValueChanged, true);
            app.PPTExample.Value = 'LES 6';
            app.ElectricParameterLabel = uilabel(app.NumPPTGUIFigure);
            app.ElectricParameterLabel.Text = 'Electric Parameter';
            app.ThrusterGeometryLabel = uilabel(app.NumPPTGUIFigure);
            app.ThrusterGeometryLabel.Text = 'Thruster Geometry';
            app.PlasmaPropertiesLabel = uilabel(app.NumPPTGUIFigure);
            app.PlasmaPropertiesLabel.Text = 'Plasma Properties';
            app.ConstantValuesLabel = uilabel(app.NumPPTGUIFigure);
            app.ConstantValuesLabel.Text = 'Constant Values';
            app.NumericalOptionsLabel = uilabel(app.NumPPTGUIFigure);
            app.NumericalOptionsLabel.Text = 'Numerical Options';
            app.OthersLabel = uilabel(app.NumPPTGUIFigure);
            app.OthersLabel.Text = 'Others';
            app.ResultsLabel = uilabel(app.NumPPTGUIFigure);
            app.ResultsLabel.Text = 'Results';
            app.PreDefinedConfigLabel = uilabel(app.NumPPTGUIFigure);
            app.PreDefinedConfigLabel.Text = 'Pre-Defined Config.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ppt_design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NumPPTGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NumPPTGUIFigure)
        end
    end
end